classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        GenerateDatasetButton           matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FEMSimulationPanel              matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.Button
        PressurekPaEditField            matlab.ui.control.NumericEditField
        PressurekPaEditFieldLabel       matlab.ui.control.Label
        MessageTextArea                 matlab.ui.control.TextArea
        MessageTextAreaLabel            matlab.ui.control.Label
        ActuatorDesignPanel             matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        SubtractButton                  matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        RotationAngledegEditField       matlab.ui.control.NumericEditField
        RotationAngledegEditFieldLabel  matlab.ui.control.Label
        MaterialPropertiesPanel         matlab.ui.container.Panel
        Densitygcm3EditField            matlab.ui.control.NumericEditField
        Densitygcm3Label                matlab.ui.control.Label
        PoissonsRatioEditField          matlab.ui.control.NumericEditField
        PoissonsRatioEditFieldLabel     matlab.ui.control.Label
        YoungsModuluskPaEditField       matlab.ui.control.NumericEditField
        YoungsModuluskPaLabel           matlab.ui.control.Label
        GeometricParametersPanel        matlab.ui.container.Panel
        ResetButton                     matlab.ui.control.Button
        DesignUpdateButton              matlab.ui.control.Button
        WallThicknesstmmEditField       matlab.ui.control.NumericEditField
        WallThicknesstmmEditFieldLabel  matlab.ui.control.Label
        InnerRadiusr_inmmEditField      matlab.ui.control.NumericEditField
        InnerRadiusr_inmmEditFieldLabel  matlab.ui.control.Label
        AverageRadiusRmmEditField       matlab.ui.control.NumericEditField
        AverageRadiusRmmEditFieldLabel  matlab.ui.control.Label
        ModuleLengthlmmEditField        matlab.ui.control.NumericEditField
        ModuleLengthlmmEditFieldLabel   matlab.ui.control.Label
        OptimisationTab                 matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        DatasetInputPanel               matlab.ui.container.Panel
        SelectDatasetButton             matlab.ui.control.Button
        GenerateCADButton               matlab.ui.control.Button
        ShapeMatchingPanel              matlab.ui.container.Panel
        MaxValuekPaEditField            matlab.ui.control.NumericEditField
        MaxValuekPaLabel                matlab.ui.control.Label
        MinValuekPaEditField            matlab.ui.control.NumericEditField
        MinValuekPaLabel                matlab.ui.control.Label
        MaxValuemmEditField             matlab.ui.control.NumericEditField
        MaxValuemmLabel                 matlab.ui.control.Label
        MinValuemmEditField             matlab.ui.control.NumericEditField
        MinValuemmLabel                 matlab.ui.control.Label
        AppliedPressure                 matlab.ui.control.Label
        OuterRadius                     matlab.ui.control.Label
        ShapeMatchingButton             matlab.ui.control.Button
        ShapeInputPanel                 matlab.ui.container.Panel
        SegmentationButton              matlab.ui.control.Button
        TolerancemmEditField            matlab.ui.control.NumericEditField
        TolerancemmLabel                matlab.ui.control.Label
        LoadShapeFileButton             matlab.ui.control.Button
        MessageTextArea_2               matlab.ui.control.TextArea
        MessageTextArea_2Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        % Get screen size
        screenSize = [1, 1, 1536, 960];
        screenWidth = 1536;
        screenHeight = 960; 
        
        % Set the figure position
        figWidth = 560;  % Set the width of the figure
        figHeight = 420; % Set the height of the figure
        AssemblyFigure = figure('Name', 'Bellow SPA Geometry', 'Visible','off', "NumberTitle", "off");
        
        AssemblyData = []; % phi, ri, t, rm, l    
        AssemblySurfHandle = [];
        AssemblyLineHandle = [];
        
        Curve = []; 
        PCC_result = [];
        UniqueArc = [];
        RepeatIndex = [];
        OptVariable = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mat_input)
            
            app.screenSize = get(groot, 'ScreenSize');
            app.screenWidth = app.screenSize(3);
            app.screenHeight = app.screenSize(4);
            app.AssemblyFigure.Position =  [(app.screenWidth - app.figWidth)/2, (app.screenHeight - app.figHeight)/2, app.figWidth, app.figHeight];
            
        end

        % Button pushed function: DesignUpdateButton
        function DesignUpdateButtonPushed(app, event)
            ri = app.InnerRadiusr_inmmEditField.Value;
            t = app.WallThicknesstmmEditField.Value;
            rm = app.AverageRadiusRmmEditField.Value;
            l = app.ModuleLengthlmmEditField.Value;
            if (ri <= 4*t) && (ri > 2*t) && (rm > ri + t) && (rm <= 2*ri) && (l >= max(4*t, ri)) && (l <= 4*(rm - ri))
                message = sprintf('Geometric constraints checked!');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                Unit_2D_horizontal(ri,t,rm,l);
                UpdateSchematicDiagram = imread("SchematicUnit.jpg");
                app.Image.ImageSource = repmat(UpdateSchematicDiagram, 1);
            else
                if (ri > 4*t)
                    message = sprintf('Criterion " r_i <= 4*t " not met.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
                if (ri <= 2*t)
                    message = sprintf('Criterion " r_i > 2*t " not met.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
                if (rm <= ri + t)
                    message = sprintf('Criterion " r_m > r_i + t " not met.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
                if (rm > 2*ri)
                    message = sprintf('Criterion " r_m <= 2*r_i " not met.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
                if (l <= max(2*t, ri))
                    message = sprintf('Criterion " l > max(2*t, r_i) " not met.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
                if (l > 4*(rm - ri))
                    drawnow 
                end
            end
            
            message = sprintf('-------------------------------------');
            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.InnerRadiusr_inmmEditField.Value = 5;
            app.WallThicknesstmmEditField.Value = 1.5;
            app.AverageRadiusRmmEditField.Value = 8;
            app.ModuleLengthlmmEditField.Value = 10;
            Unit_2D_horizontal(5,1.5,8,10);
            UpdateSchematicDiagram = imread("SchematicUnit.jpg");
            app.Image.ImageSource = repmat(UpdateSchematicDiagram, 1);
            message = sprintf('All geometric parameters set back to the default values.');
            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
            message = sprintf('-------------------------------------');
            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            phi = app.RotationAngledegEditField.Value*pi/180;
            ri = app.InnerRadiusr_inmmEditField.Value;
            t = app.WallThicknesstmmEditField.Value;
            rm = app.AverageRadiusRmmEditField.Value;
            l = app.ModuleLengthlmmEditField.Value;
            app.AssemblyData = [app.AssemblyData; [phi ri t rm l]];
            
            if size(app.AssemblyData,1) > 1 && ((ri ~= app.AssemblyData(end-1,2)) || (t ~= app.AssemblyData(end-1,3)))
                message = sprintf('The r_in and t must be the same as previous ones.');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
            else
                x = 0;
                y = 0;
                z = -(sum(app.AssemblyData(:,end)) - app.AssemblyData(end,end));
                
                if ~isvalid(app.AssemblyFigure)
                    NewAssemblyFigure = figure('Name', 'Bellow SPA Geometry', 'Visible','off', "NumberTitle", "off");
                    NewAssemblyFigure.Position =  [(app.screenWidth - app.figWidth)/2, (app.screenHeight - app.figHeight)/2, app.figWidth, app.figHeight];
                    NewAssemblySurfHandle = [];
                    NewAssemblyLineHandle = [];
                    
                    for i = 1:size(app.AssemblyData,1)
                        x = 0;
                        y = 0;
                        z = -(sum(app.AssemblyData(1:i,end)) - app.AssemblyData(i,end));
                        [NewAssemblyFigure, NewAssemblySurfHandle, NewAssemblyLineHandle] = Unit_3D_add(x,y,z,[phi ri t rm l], NewAssemblyFigure, NewAssemblySurfHandle, NewAssemblyLineHandle);
                    end
                    app.AssemblyFigure = NewAssemblyFigure;
                    app.AssemblySurfHandle = NewAssemblySurfHandle;
                    app.AssemblyLineHandle = NewAssemblyLineHandle;
                    set(app.AssemblyFigure, 'visible', 'on');

                    
                else
                    [app.AssemblyFigure, app.AssemblySurfHandle, app.AssemblyLineHandle] = Unit_3D_add(x,y,z,[phi ri t rm l], app.AssemblyFigure, app.AssemblySurfHandle, app.AssemblyLineHandle);
                    set(app.AssemblyFigure, 'visible', 'on');
                    message = sprintf('One bellow is added.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                    message = sprintf('-------------------------------------');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];


                end
            end
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: SubtractButton
        function SubtractButtonPushed(app, event)
            if ~isvalid(app.AssemblyFigure)
                
                NewAssemblyFigure = figure('Name', 'Bellow SPA Geometry', 'Visible','off', "NumberTitle", "off");
                NewAssemblyFigure.Position =  [(app.screenWidth - app.figWidth)/2, (app.screenHeight - app.figHeight)/2, app.figWidth, app.figHeight];
                NewAssemblySurfHandle = [];
                NewAssemblyLineHandle = [];
                
                if size(app.AssemblyData,1) > 1
                    app.AssemblyData(end,:) = [];
                    for i = 1:size(app.AssemblyData,1)
                        x = 0;
                        y = 0;
                        z = -(sum(app.AssemblyData(1:i,end)) - app.AssemblyData(i,end));
                        [NewAssemblyFigure, NewAssemblySurfHandle, NewAssemblyLineHandle] = Unit_3D_add(x,y,z,app.AssemblyData(i,:), NewAssemblyFigure, NewAssemblySurfHandle, NewAssemblyLineHandle);
                    end
                    
                    app.AssemblyFigure = NewAssemblyFigure;
                    app.AssemblySurfHandle = NewAssemblySurfHandle;
                    app.AssemblyLineHandle = NewAssemblyLineHandle;
                    set(app.AssemblyFigure, 'visible', 'on');

                end
            else
                if ~isempty(app.AssemblySurfHandle)
                    disp(app.AssemblyData);
                    figure(app.AssemblyFigure);
                    delete(app.AssemblySurfHandle(end,:));
                    delete(app.AssemblyLineHandle(end,:));
                    app.AssemblySurfHandle(end,:) = [];
                    app.AssemblyLineHandle(end,:) = [];
                    app.AssemblyData(end,:) = [];
                    message = sprintf('One bellow is substracted.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                    message = sprintf('-------------------------------------');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                    
                end
            end
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            if ~isempty(app.AssemblySurfHandle)
                delete(app.AssemblySurfHandle);
                delete(app.AssemblyLineHandle);
                app.AssemblySurfHandle = [];
                app.AssemblyLineHandle = [];
                app.AssemblyData = [];
                message = sprintf('The design is cleared.');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];

            end
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            if ~isempty(app.AssemblyData)
                % identify repeated bellow units
                uniqueUnit = [];
                app.RepeatIndex = [];
                for i = 1:size(app.AssemblyData,1)
                    if isempty(uniqueUnit)
                        uniqueUnit = [uniqueUnit; app.AssemblyData(1,2:end)];
                        app.RepeatIndex = [app.RepeatIndex 1];
                    else
                        for j = 1:size(uniqueUnit,1)
                            if app.AssemblyData(i,2:end) == uniqueUnit(j,:)
                                app.RepeatIndex = [app.RepeatIndex j];
                            else
                                continue;
                            end
                        end
                        if length(app.RepeatIndex) < i
                            uniqueUnit = [uniqueUnit; app.AssemblyData(i,2:end)];
                            app.RepeatIndex = [app.RepeatIndex size(uniqueUnit,1)];
                        end
                        
                    end
                end
                disp(uniqueUnit);
                disp(app.RepeatIndex);
            end
            
            pressure = app.PressurekPaEditField.Value; % kPa
            material = [app.YoungsModuluskPaEditField.Value, app.PoissonsRatioEditField.Value, app.Densitygcm3EditField.Value*1000];
            uniqueUnitResult = UniqueUnitSimulation(uniqueUnit, pressure*1000, material);

            all_valid = true;
            for i = 1:length(uniqueUnitResult)
                if uniqueUnitResult(i) == -1
                    % failed simulation
                    all_valid = false;
                    for j = 1:length(app.RepeatIndex)
                        if app.RepeatIndex(j) == i
                            message = sprintf('Bellow number ' + num2str(j) + ' fails to be simulated.');
                            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                            message = sprintf('-------------------------------------');
                            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                            break;
                        else
                            continue;
                        end
                    end
                    break;
                    
                elseif uniqueUnitResult(i) == -2
                    % flank requirement doesn't meet
                    all_valid = false;
                    for j = 1:length(app.RepeatIndex)
                        if app.RepeatIndex(j) == i
                            message = sprintf('Bellow number ' + num2str(j) + ' does not meet the criterion of "ro - ri - l/2 > 0".');
                            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                            message = sprintf('-------------------------------------');
                            app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                            break;
                        else
                            continue;
                        end
                    end
                    break;
                else
                    all_valid = true;
                end
            end
            if all_valid
                if length(uniqueUnitResult) == size(uniqueUnit,1)
                    FK = SimulationFK(app.AssemblyData, app.RepeatIndex, uniqueUnitResult);
                    set(FK,'visible', 'on');
                    
                    % output a table of the deformation of each module
                    % Create a new figure for the table
                    tableFig = figure('Name', 'Simulation Results', 'NumberTitle', 'off');
                    tableFig.Position = [250 300 1000 300];
                    
                    % Define column headers
                    headers = {'Module Index', 'Rotation Angle (rad)', 'Inner Radius (mm)', 'Wall Thickness (mm)',...
                    'Average Radius (mm)', 'Module Length (mm)', 'Actuation Pressure (kPa)',...
                    'Angular Deformation (rad)'};

                    % Create the uitable in the new figure
                    tableUI = uitable(tableFig);
                    tableUI.Position = [0 0 1000 300];
                    
                    % Set the data and column headers to the table
                    deformation_col = [];
                    for i = 1: size(app.AssemblyData, 1)
                        deformation_col = [deformation_col; uniqueUnitResult(app.RepeatIndex(i))];
                    end
                    
                    data = [(1:size(app.AssemblyData, 1))', app.AssemblyData, pressure*ones(size(app.AssemblyData, 1),1), deformation_col];
                    tableUI.Data = data;
                    tableUI.ColumnName = headers;
                    
                    message = sprintf('The FEM simulation is done.');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                    message = sprintf('-------------------------------------');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
            end
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: GenerateDatasetButton
        function GenerateDatasetButtonPushed(app, event)
            % if users provide a different material
            E = app.YoungsModuluskPaEditField.Value; %kpa
            nu = app.PoissonsRatioEditField.Value;
            rho = app.Densitygcm3EditField.Value; %g/cm^3
            
            if (E ~= 545.8)||(nu ~= 0.49)||(rho ~= 1.16)
                % collect new dataset based on the input material
                [filename,filepath] = uiputfile('*.mat');
                
                if ~isequal(filename,0)
                    [sample_no, material_name, material_value, variable_name, variable_value, result_name, result_value]=GenerateDataSet(E,nu,rho); % Dataset saved as "NewDataSet.mat"
                    outputfile = fullfile(filepath, filename);
                    save(outputfile, 'sample_no', 'material_name', 'material_value', 'variable_name', 'variable_value', 'result_name', 'result_value');
                    message = sprintf('The dataset %s is saved under %s.', filename, filepath);
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                    message = sprintf('-------------------------------------');
                    app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                end
            else
                message = sprintf('There is already a dataset of material Agilus30.');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea.Value = [app.MessageTextArea.Value(:)', {message}];
            end
            MessageTextAreaValueChanged(app, []);
        end

        % Button pushed function: SelectDatasetButton
        function SelectDatasetButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat');
            [currentScriptPath,~,~] = fileparts(mfilename('fullpath'));
            if ~isequal(filename, 0)
                message = sprintf('Dataset %s is selected!', filename);
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                ANNfile = ANNTraining(filename,filepath,currentScriptPath);
                
                message = sprintf('The new ANN model %s is generated!', ANNfile);
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                
            end
            MessageTextArea_2ValueChanged(app, []);
        end

        % Button pushed function: LoadShapeFileButton
        function LoadShapeFileButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.mat');
            if ~isequal(filename,0)
                message = sprintf('File %s successfully loaded!', filename);
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                fullPath = fullfile(filepath, filename);
                app.Curve = load(fullPath).curve;
                LoadedShape = figure('Name', 'Target Shape', 'Visible','on', "NumberTitle", "off");
                LoadedShape.Position =  [(app.screenWidth - app.figWidth)/2, (app.screenHeight - app.figHeight)/2, app.figWidth, app.figHeight];
                hold on
                axis equal
                xlabel('X (mm)')
                ylabel('Y (mm)')
                zlabel('Z (mm)')
                plot3(app.Curve(1,:), app.Curve(2,:), app.Curve(3,:), 'LineWidth', 2)
                legend('Target Shape Curve')
                hold off

            end
            
            MessageTextArea_2ValueChanged(app, []);
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            if isempty(app.Curve)
                message = sprintf('There is no shape input!');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
            else
                % get the tolerance for segmentation
                tol = app.TolerancemmEditField.Value;
                [bspline, tangential_vectors] = BSpline_approx(app.Curve);
                [PCC_figure, app.PCC_result] = Biarc_segmentation(bspline, tangential_vectors, tol);
                
                if ~isempty(app.PCC_result)
                    % find the max_curvature if exist
                    max_curvature = 0;
                    for i = 1:size(app.PCC_result,2)
                        if app.PCC_result(i).type == 2
                            max_curvature = max(max_curvature, app.PCC_result(i).angle/app.PCC_result(i).length);
                        end
                    end
                    if max_curvature ~= 0
                        app.MaxValuemmEditField.Value = 1/max_curvature;
                    end
                    
                    
                    message = sprintf('Shape segmentation has been done!');
                    app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                    message = sprintf('-------------------------------------');
                    app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                    

                    rotation = [];
                    for i = 1: size(app.PCC_result,2)
                        rotation = [rotation app.PCC_result(i).rotation];
                    end
                    disp('The segmentation rotation is');
                    disp(rotation);
                    
                    % output a table of the segmented results
                    % Create a new figure for the table
                    tableFig = figure('Name', 'Segmented Results', 'NumberTitle', 'off');
                    tableFig.Position = [400 300 700 300];
                    
                    % Define column headers
                    headers = {'Segment Index', 'Line-1/Arc-2', 'Length (mm)', 'Curvature (1/mm)','Segment Plane Rotation Angle (rad)', 'Unique Arc Index'};

                    % Create the uitable in the new figure
                    tableUI = uitable(tableFig);
                    tableUI.Position = [0 0 700 300];
                    
                    
                    % separate the line and arc segments
                    PCC_line = [];
                    PCC_arc = [];
                    for i = 1:size(app.PCC_result, 2)
                        if app.PCC_result(i).type == 1
                            PCC_line = [PCC_line, app.PCC_result(i)]; 
                        else
                            PCC_arc = [PCC_arc, app.PCC_result(i)]; 
                        end
                    end
                    
                    % post-process the segmentation results
                    % only the arc segments will be put into optimisation
                    if isempty(PCC_arc)
                        disp('No arc segments');
                        app.UniqueArc = []; % arc_length, curvature
                        app.RepeatIndex = [];
                    else
                        % find repeated arc segments
                        app.UniqueArc = []; % arc_length, curvature
                        app.RepeatIndex = [];
                        for i = 1:size(PCC_arc,2)
                            if isempty(app.UniqueArc)
                                app.UniqueArc = [app.UniqueArc; [PCC_arc(1).length, PCC_arc(1).angle/PCC_arc(1).length]];
                                app.RepeatIndex = [app.RepeatIndex 1];
                            else
                                for j = 1:size(app.UniqueArc,1)
                                    
                                    if (abs(PCC_arc(i).length - app.UniqueArc(j,1)) <= 2) && (abs(PCC_arc(i).angle/PCC_arc(i).length - app.UniqueArc(j,2)) <= 5e-3*app.UniqueArc(j,2))
                                        app.RepeatIndex = [app.RepeatIndex j];
                                        break; % to avoid the situation that an arc is close to several unique arcs
                                    else
                                        continue;
                                    end
                                end
                                if length(app.RepeatIndex) < i
                                    app.UniqueArc = [app.UniqueArc; [PCC_arc(i).length, PCC_arc(i).angle/PCC_arc(i).length]];
                                    app.RepeatIndex = [app.RepeatIndex size(app.UniqueArc,1)];
                                end
                                
                            end
                        end
                    end
                    
                    %  Set the data and column headers to the table
                    data = [];
                    j = 1;
                    for i = 1:size(app.PCC_result, 2)
                        if app.PCC_result(i).type == 1
                            type = 'Line';
                            Arc_repeatIndex = NaN;
                        else
                            type = 'Arc';
                            Arc_repeatIndex = app.RepeatIndex(j);
                            j = j+1;
                        end
                        data = [data; [i, app.PCC_result(i).type, app.PCC_result(i).length, app.PCC_result(i).angle/app.PCC_result(i).length, app.PCC_result(i).rotation, Arc_repeatIndex]];
                    end
                    tableUI.Data = data;
                    tableUI.ColumnName = headers;
                    
                    % visulize the segmentation results
                    set(PCC_figure, 'visible', 'on');
                    
                end

            end
            MessageTextArea_2ValueChanged(app, []);
        end

        % Button pushed function: ShapeMatchingButton
        function ShapeMatchingButtonPushed(app, event)
            
            if isempty(app.PCC_result)
                message = sprintf('There is no shape input!');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
            else

                % only the arc segments will be put into optimisation
                if isempty(app.UniqueArc)
                    disp('No arc segments');
                else

                    max_curvature = max(app.UniqueArc(:,2));
                    
                    
                    if (app.MinValuemmEditField.Value > 1/max_curvature)
                        message = sprintf('The lower bound of outer radius can not exceed the minimal radius of the segmented arcs!');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        message = sprintf('-------------------------------------');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        app.MinValuemmEditField.Value = 0;
                    elseif (app.MaxValuemmEditField.Value > 1/max_curvature)
                        message = sprintf('The upper bound of outer radius can not exceed the minimal radius of the segmented arcs!');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        message = sprintf('-------------------------------------');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        app.MaxValuemmEditField.Value = 1/max_curvature;
                    elseif (app.MinValuemmEditField.Value > app.MaxValuemmEditField.Value)
                        message = sprintf('The lower bound of outer radius can not exceed its upper bound!');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        message = sprintf('-------------------------------------');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        app.MinValuemmEditField.Value = 0;
                        app.MaxValuemmEditField.Value = 1/max_curvature;
                    elseif (app.MinValuekPaEditField.Value > app.MaxValuekPaEditField.Value)
                        message = sprintf('The lower bound of applied pressure can not exceed its upper bound!');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        message = sprintf('-------------------------------------');
                        app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                        app.MinValuekPaEditField.Value = 0;
                        app.MaxValuekPaEditField.Value = 10;
                    else
                        % get the boundary conditions
                        ro_lb = app.MinValuemmEditField.Value;
                        ro_ub = app.MaxValuemmEditField.Value;
                        P_lb = app.MinValuekPaEditField.Value*1000;
                        P_ub = app.MaxValuekPaEditField.Value*1000;
                        
                        BD = [ro_lb, ro_ub; P_lb, P_ub];
                        
                        [app.OptVariable, optValue] = GAOpt(app.UniqueArc, BD);

                            disp([app.OptVariable(1),app.OptVariable(2), app.OptVariable(3)/1000, app.OptVariable(4:end)]);
                            
                            ri = app.OptVariable(1); % mm
                            t = app.OptVariable(2); % mm
                            P = app.OptVariable(3)/1000; % kPa
                            rm = [];
                            l = [];
                            new_l = [];
                            load NewANN.mat
                            for N = 1:(length(app.OptVariable)-3)/2
                                rm = [rm app.OptVariable(3+(N-1)*2+1)];
                                l = [l app.OptVariable(3+(N-1)*2+2)];
                                theta = 2*sim(net,[ri, t, app.OptVariable(3+2*(N-1)+1), app.OptVariable(3+2*(N-1)+2), P*1000]');
                                new_l = [new_l theta*(l(end)/theta + ri + t/2)];
                            end
                            MatchingCompare(app.Curve, app.PCC_result, app.UniqueArc, app.RepeatIndex, app.OptVariable);
                            message = sprintf('The shape matching is done.');
                            app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                            message = sprintf('-------------------------------------');
                            app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];


                        % output a table of the optimisation results
                        % Create a new figure for the table
                        tableFig = figure('Name', 'Optimisation Results', 'NumberTitle', 'off');
                        tableFig.Position = [350 300 800 300];
                        
                        % Define column headers
                        headers = {'Unique Arc Index', 'Inner Radius (mm)', 'Wall Thickness (mm)',...
                        'Actuation Pressure (kPa)', 'Average Radius (mm)', 'Module Length (mm)',...
                        'Number of Modules'};
    
                        % Create the uitable in the new figure
                        tableUI = uitable(tableFig);
                        tableUI.Position = [0 0 800 300];
                        
                        % Set the data and column headers to the table
                        data = [];
                        for i = 1: (length(app.OptVariable)-3)/2
                            data = [data; i, app.OptVariable(1), app.OptVariable(2), app.OptVariable(3)/1000, app.OptVariable(3+i*2-1), app.OptVariable(3+2*i), round(app.UniqueArc(i,1)/new_l(i))];
                        end
                        
                        tableUI.Data = data;
                        tableUI.ColumnName = headers;
                    end
                   
                end
                
            end
            MessageTextArea_2ValueChanged(app, []);
        end

        % Button pushed function: GenerateCADButton
        function GenerateCADButtonPushed(app, event)
            if isempty(app.OptVariable)
                message = sprintf('No shape matching result!');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
            else
                [filename, filepath] = uiputfile({'*.stl','STL files (*.stl)'});
                [~, filename, ~] = fileparts(filename);
                ComputeCad(filepath, filename, app.PCC_result, app.UniqueArc, app.RepeatIndex, app.OptVariable);
                message = sprintf('The CAD file %s is saved under %s.', filename, filepath);
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
                message = sprintf('-------------------------------------');
                app.MessageTextArea_2.Value = [app.MessageTextArea_2.Value(:)', {message}];
            end
                
            MessageTextArea_2ValueChanged(app, []);
            
        end

        % Value changed function: MessageTextArea
        function MessageTextAreaValueChanged(app, event)
            % Scroll to the end of the text
            scroll(app.MessageTextArea, 'bottom')
        end

        % Value changed function: MessageTextArea_2
        function MessageTextArea_2ValueChanged(app, event)
            
            scroll(app.MessageTextArea_2, 'bottom')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.GeometricParametersPanel = uipanel(app.SimulationTab);
            app.GeometricParametersPanel.Title = 'Geometric Parameters';
            app.ModuleLengthlmmEditFieldLabel = uilabel(app.GeometricParametersPanel);
            app.ModuleLengthlmmEditFieldLabel.Text = 'Module Length l (mm)';
            app.ModuleLengthlmmEditField = uieditfield(app.GeometricParametersPanel, 'numeric');
            app.ModuleLengthlmmEditField.Value = 10;
            app.AverageRadiusRmmEditFieldLabel = uilabel(app.GeometricParametersPanel);
            app.AverageRadiusRmmEditFieldLabel.Text = 'Average Radius R (mm)';
            app.AverageRadiusRmmEditField = uieditfield(app.GeometricParametersPanel, 'numeric');
            app.AverageRadiusRmmEditField.Value = 8;
            app.InnerRadiusr_inmmEditFieldLabel = uilabel(app.GeometricParametersPanel);
            app.InnerRadiusr_inmmEditFieldLabel.Text = 'Inner Radius r_in (mm)';
            app.InnerRadiusr_inmmEditField = uieditfield(app.GeometricParametersPanel, 'numeric');
            app.InnerRadiusr_inmmEditField.Value = 5;
            app.WallThicknesstmmEditFieldLabel = uilabel(app.GeometricParametersPanel);
            app.WallThicknesstmmEditFieldLabel.Text = 'Wall Thickness t (mm)';
            app.WallThicknesstmmEditField = uieditfield(app.GeometricParametersPanel, 'numeric');
            app.WallThicknesstmmEditField.Value = 1.5;
            app.DesignUpdateButton = uibutton(app.GeometricParametersPanel, 'push');
            app.DesignUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @DesignUpdateButtonPushed, true);
            app.DesignUpdateButton.Text = 'Design Update';
            app.ResetButton = uibutton(app.GeometricParametersPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.MaterialPropertiesPanel = uipanel(app.SimulationTab);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.YoungsModuluskPaLabel = uilabel(app.MaterialPropertiesPanel);
            app.YoungsModuluskPaLabel.Text = 'Young''s Modulus (kPa)';
            app.YoungsModuluskPaEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.YoungsModuluskPaEditField.Value = 545.8;
            app.PoissonsRatioEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.PoissonsRatioEditFieldLabel.Text = 'Poisson''s Ratio';
            app.PoissonsRatioEditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PoissonsRatioEditField.Value = 0.49;
            app.Densitygcm3Label = uilabel(app.MaterialPropertiesPanel);
            app.Densitygcm3Label.Text = 'Density (g/cm^3)';
            app.Densitygcm3EditField = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Densitygcm3EditField.Value = 1.16;
            app.ActuatorDesignPanel = uipanel(app.SimulationTab);
            app.ActuatorDesignPanel.Title = 'Actuator Design';
            app.RotationAngledegEditFieldLabel = uilabel(app.ActuatorDesignPanel);
            app.RotationAngledegEditFieldLabel.Text = 'Rotation Angle [deg]';
            app.RotationAngledegEditField = uieditfield(app.ActuatorDesignPanel, 'numeric');
            app.AddButton = uibutton(app.ActuatorDesignPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.SubtractButton = uibutton(app.ActuatorDesignPanel, 'push');
            app.SubtractButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractButtonPushed, true);
            app.SubtractButton.Text = 'Subtract';
            app.ClearButton = uibutton(app.ActuatorDesignPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.MessageTextAreaLabel = uilabel(app.SimulationTab);
            app.MessageTextAreaLabel.Text = 'Message';
            app.MessageTextArea = uitextarea(app.SimulationTab);
            app.MessageTextArea.ValueChangedFcn = createCallbackFcn(app, @MessageTextAreaValueChanged, true);
            app.MessageTextArea.Tag = 'TextArea1';
            app.FEMSimulationPanel = uipanel(app.SimulationTab);
            app.FEMSimulationPanel.Title = 'FEM Simulation';
            app.PressurekPaEditFieldLabel = uilabel(app.FEMSimulationPanel);
            app.PressurekPaEditFieldLabel.Text = 'Pressure [kPa]';
            app.PressurekPaEditField = uieditfield(app.FEMSimulationPanel, 'numeric');
            app.SimulateButton = uibutton(app.FEMSimulationPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.SimulationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Default2DUnit.jpg');
            app.GenerateDatasetButton = uibutton(app.SimulationTab, 'push');
            app.GenerateDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateDatasetButtonPushed, true);
            app.GenerateDatasetButton.Text = 'Generate Dataset';
            app.OptimisationTab = uitab(app.TabGroup);
            app.OptimisationTab.Title = 'Optimisation';
            app.MessageTextArea_2Label = uilabel(app.OptimisationTab);
            app.MessageTextArea_2Label.Text = 'Message';
            app.MessageTextArea_2 = uitextarea(app.OptimisationTab);
            app.MessageTextArea_2.ValueChangedFcn = createCallbackFcn(app, @MessageTextArea_2ValueChanged, true);
            app.ShapeInputPanel = uipanel(app.OptimisationTab);
            app.ShapeInputPanel.Title = 'Shape Input';
            app.LoadShapeFileButton = uibutton(app.ShapeInputPanel, 'push');
            app.LoadShapeFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadShapeFileButtonPushed, true);
            app.LoadShapeFileButton.Text = 'Load Shape File';
            app.TolerancemmLabel = uilabel(app.ShapeInputPanel);
            app.TolerancemmLabel.Text = 'Tolerance (mm)';
            app.TolerancemmEditField = uieditfield(app.ShapeInputPanel, 'numeric');
            app.TolerancemmEditField.Limits = [0 Inf];
            app.TolerancemmEditField.Value = 0.5;
            app.SegmentationButton = uibutton(app.ShapeInputPanel, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segmentation';
            app.ShapeMatchingPanel = uipanel(app.OptimisationTab);
            app.ShapeMatchingPanel.Title = 'Shape Matching';
            app.ShapeMatchingButton = uibutton(app.ShapeMatchingPanel, 'push');
            app.ShapeMatchingButton.ButtonPushedFcn = createCallbackFcn(app, @ShapeMatchingButtonPushed, true);
            app.ShapeMatchingButton.Text = 'Shape Matching';
            app.OuterRadius = uilabel(app.ShapeMatchingPanel);
            app.OuterRadius.Text = 'Outer Radius r_ou';
            app.AppliedPressure = uilabel(app.ShapeMatchingPanel);
            app.AppliedPressure.Text = 'Applied Pressure P';
            app.MinValuemmLabel = uilabel(app.ShapeMatchingPanel);
            app.MinValuemmLabel.Text = 'Min. Value (mm)';
            app.MinValuemmEditField = uieditfield(app.ShapeMatchingPanel, 'numeric');
            app.MinValuemmEditField.Limits = [0 Inf];
            app.MaxValuemmLabel = uilabel(app.ShapeMatchingPanel);
            app.MaxValuemmLabel.Text = 'Max. Value (mm)';
            app.MaxValuemmEditField = uieditfield(app.ShapeMatchingPanel, 'numeric');
            app.MaxValuemmEditField.Limits = [0 Inf];
            app.MinValuekPaLabel = uilabel(app.ShapeMatchingPanel);
            app.MinValuekPaLabel.Text = 'Min. Value (kPa)';
            app.MinValuekPaEditField = uieditfield(app.ShapeMatchingPanel, 'numeric');
            app.MinValuekPaEditField.Limits = [0 Inf];
            app.MaxValuekPaLabel = uilabel(app.ShapeMatchingPanel);
            app.MaxValuekPaLabel.Text = 'Max. Value (kPa)';
            app.MaxValuekPaEditField = uieditfield(app.ShapeMatchingPanel, 'numeric');
            app.MaxValuekPaEditField.Limits = [0 Inf];
            app.MaxValuekPaEditField.Value = 10;
            app.GenerateCADButton = uibutton(app.OptimisationTab, 'push');
            app.GenerateCADButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCADButtonPushed, true);
            app.GenerateCADButton.Text = 'Generate CAD';
            app.DatasetInputPanel = uipanel(app.OptimisationTab);
            app.DatasetInputPanel.Title = 'Dataset Input';
            app.SelectDatasetButton = uibutton(app.DatasetInputPanel, 'push');
            app.SelectDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDatasetButtonPushed, true);
            app.SelectDatasetButton.Text = 'Select Dataset';
            app.Image_2 = uiimage(app.OptimisationTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Default2DUnit.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end